classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        houghTab                 matlab.ui.container.Tab
        y4EditField              matlab.ui.control.NumericEditField
        y4EditFieldLabel         matlab.ui.control.Label
        y3EditField              matlab.ui.control.NumericEditField
        y3EditFieldLabel         matlab.ui.control.Label
        x4EditField              matlab.ui.control.NumericEditField
        x4EditFieldLabel         matlab.ui.control.Label
        x3EditField              matlab.ui.control.NumericEditField
        x3EditFieldLabel         matlab.ui.control.Label
        y1EditField              matlab.ui.control.NumericEditField
        y1EditFieldLabel         matlab.ui.control.Label
        x1EditField              matlab.ui.control.NumericEditField
        x1EditFieldLabel         matlab.ui.control.Label
        y2EditField              matlab.ui.control.NumericEditField
        y2EditFieldLabel         matlab.ui.control.Label
        x2EditField              matlab.ui.control.NumericEditField
        x2EditFieldLabel         matlab.ui.control.Label
        UseROICheckBox           matlab.ui.control.CheckBox
        ROILabel                 matlab.ui.control.Label
        hough_UploadImageButton  matlab.ui.control.Button
        hough_base_img           matlab.ui.control.Image
        hough_res_axes           matlab.ui.control.UIAxes
        ObjectSegmentationTab    matlab.ui.container.Tab
        objseg_edge_img          matlab.ui.control.Image
        objseg_result_img        matlab.ui.control.Image
        ButtonGroup              matlab.ui.container.ButtonGroup
        Laplace                  matlab.ui.control.RadioButton
        LoG                      matlab.ui.control.RadioButton
        Sobel                    matlab.ui.control.RadioButton
        Prewitt                  matlab.ui.control.RadioButton
        Roberts                  matlab.ui.control.RadioButton
        Canny                    matlab.ui.control.RadioButton
        objseg_upload_image      matlab.ui.control.Button
        objseg_base_img          matlab.ui.control.Image
    end

    
    properties (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: hough_UploadImageButton
        function hough_UploadImageButtonPushed(app, event)
           [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end
            img = imread(fullfile(path, file));
            app.hough_base_img.ImageSource=img;
            i=im2gray(img);
            bw=imgaussfilt(i);
            bw=imbinarize(bw,0.5);
            [m,n,c]=size(bw);
            if(app.UseROICheckBox.Value)
                roi_vertices = [ ...
                    n*app.x1EditField.Value, m*app.y1EditField.Value;
                    n*app.x2EditField.Value, m*app.y2EditField.Value;
                    n*app.x3EditField.Value, m*app.y3EditField.Value;
                    n*app.x4EditField.Value, m*app.y4EditField.Value;];
                mask=poly2mask(roi_vertices(:,1), roi_vertices(:,2), m, n);
                bw = bw & mask;
            end
            [h,theta,rho]=houghtr(bw);
            hpeaks=houghpeaks(h,5,'threshold',ceil(0.3*max(h(:))));
            x=theta(hpeaks(:,2));
            y=rho(hpeaks(:,2));
            hlines=houghlines(bw,theta,rho,hpeaks,'FillGap',5,'MinLength',7);
            
            % figure, imshow(img), hold on
            imshow(img,Parent=app.hough_res_axes);
            hold(app.hough_res_axes,"on");
            max_len=0;
            xy_long=zeros(2,2);
            for k=1:length(hlines)
                xy=[hlines(k).point1;hlines(k).point2];
                plot(app.hough_res_axes,xy(:,1),xy(:,2),'LineWidth',2,'Color','green');
            
                plot(app.hough_res_axes,xy(1,1),xy(1,2),'x','LineWidth',2,'Color','yellow');
                plot(app.hough_res_axes,xy(2,1),xy(2,2),'x','LineWidth',2,'Color','red');
                len = norm(hlines(k).point1 - hlines(k).point2);
                if ( len > max_len) 
                    max_len = len; 
                    xy_long = xy; 
                end
            end
            plot(app.hough_res_axes,xy_long(:,1),xy_long(:,2),'LineWidth',2,'Color','red');
            hold(app.hough_res_axes,"off");
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject.Text;
            method = lower(selectedButton);

            if ~isempty(app.objseg_base_img.ImageSource)
                [result, result_edge] = objectsegmentation(app.objseg_base_img.ImageSource, method);
                app.objseg_result_img.ImageSource = result;
                app.objseg_edge_img.ImageSource = cat(3, result_edge, result_edge, result_edge);
            end
        end

        % Button pushed function: objseg_upload_image
        function objseg_upload_imageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end

            img = imread(fullfile(path, file));
            if size(img, 3) < 3
                img = cat(3, img, img, img);
            end

            app.objseg_base_img.ImageSource=img;
            [result, result_edge] = objectsegmentation(img, lower(app.ButtonGroup.SelectedObject.Text));
            app.objseg_result_img.ImageSource = result;
            app.objseg_edge_img.ImageSource = cat(3, result_edge, result_edge, result_edge);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.houghTab = uitab(app.TabGroup);
            app.houghTab.Title = 'hough';
            app.hough_res_axes = uiaxes(app.houghTab);
            title(app.hough_res_axes, 'Result')
            app.hough_res_axes.XTick = [];
            app.hough_res_axes.YTick = [];
            app.hough_base_img = uiimage(app.houghTab);
            app.hough_UploadImageButton = uibutton(app.houghTab, 'push');
            app.hough_UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @hough_UploadImageButtonPushed, true);
            app.hough_UploadImageButton.Text = 'Upload Image';
            app.ROILabel = uilabel(app.houghTab);
            app.ROILabel.Text = 'ROI';
            app.UseROICheckBox = uicheckbox(app.houghTab);
            app.UseROICheckBox.Text = 'Use ROI';
            app.x2EditFieldLabel = uilabel(app.houghTab);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.houghTab, 'numeric');
            app.y2EditFieldLabel = uilabel(app.houghTab);
            app.y2EditFieldLabel.Text = 'y2';
            app.y2EditField = uieditfield(app.houghTab, 'numeric');
            app.x1EditFieldLabel = uilabel(app.houghTab);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.houghTab, 'numeric');
            app.y1EditFieldLabel = uilabel(app.houghTab);
            app.y1EditFieldLabel.Text = 'y1';
            app.y1EditField = uieditfield(app.houghTab, 'numeric');
            app.x3EditFieldLabel = uilabel(app.houghTab);
            app.x3EditFieldLabel.Text = 'x3';
            app.x3EditField = uieditfield(app.houghTab, 'numeric');
            app.x4EditFieldLabel = uilabel(app.houghTab);
            app.x4EditFieldLabel.Text = 'x4';
            app.x4EditField = uieditfield(app.houghTab, 'numeric');
            app.y3EditFieldLabel = uilabel(app.houghTab);
            app.y3EditFieldLabel.Text = 'y3';
            app.y3EditField = uieditfield(app.houghTab, 'numeric');
            app.y4EditFieldLabel = uilabel(app.houghTab);
            app.y4EditFieldLabel.Text = 'y4';
            app.y4EditField = uieditfield(app.houghTab, 'numeric');
            app.ObjectSegmentationTab = uitab(app.TabGroup);
            app.ObjectSegmentationTab.Title = 'Object Segmentation';
            app.objseg_base_img = uiimage(app.ObjectSegmentationTab);
            app.objseg_upload_image = uibutton(app.ObjectSegmentationTab, 'push');
            app.objseg_upload_image.ButtonPushedFcn = createCallbackFcn(app, @objseg_upload_imageButtonPushed, true);
            app.objseg_upload_image.Text = 'Upload Image';
            app.ButtonGroup = uibuttongroup(app.ObjectSegmentationTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.Canny = uiradiobutton(app.ButtonGroup);
            app.Canny.Text = 'Canny';
            app.Roberts = uiradiobutton(app.ButtonGroup);
            app.Roberts.Text = 'Roberts';
            app.Prewitt = uiradiobutton(app.ButtonGroup);
            app.Prewitt.Text = 'Prewitt';
            app.Sobel = uiradiobutton(app.ButtonGroup);
            app.Sobel.Text = 'Sobel';
            app.LoG = uiradiobutton(app.ButtonGroup);
            app.LoG.Text = 'LoG';
            app.Laplace = uiradiobutton(app.ButtonGroup);
            app.Laplace.Text = 'Laplace';
            app.Laplace.Value = true;
            app.objseg_result_img = uiimage(app.ObjectSegmentationTab);
            app.objseg_edge_img = uiimage(app.ObjectSegmentationTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end